classdef SLOPEY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup2                      matlab.ui.container.TabGroup
        RGBTab                         matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        RegionalSlopeTab               matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        NumberofregionsSpinner         matlab.ui.control.Spinner
        NumberofregionsSpinnerLabel    matlab.ui.control.Label
        ChannelDropDown                matlab.ui.control.DropDown
        ChannelDropDownLabel           matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        SelectRegionsButton            matlab.ui.control.Button
        SelecsubstrateregionButton     matlab.ui.control.Button
        LoadOMimagesButton             matlab.ui.control.StateButton
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        SlopemapTab                    matlab.ui.container.Tab
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        SavedataButton                 matlab.ui.control.StateButton
        ChannelDropDown_2              matlab.ui.control.DropDown
        ChannelDropDown_2Label         matlab.ui.control.Label
        CalculateButton_2              matlab.ui.control.Button
        SelectRegionofInterestButton   matlab.ui.control.Button
        SelecsubstrateregionButton_2   matlab.ui.control.Button
        LoadOMimagesButton_2           matlab.ui.control.StateButton
        UIAxes_8                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        RAWTab                         matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        RegionalratioTab               matlab.ui.container.Tab
        CalculateButton_3              matlab.ui.control.Button
        NumberofregionsSpinner_2       matlab.ui.control.Spinner
        NumberofregionsSpinner_2Label  matlab.ui.control.Label
        UITable_2                      matlab.ui.control.Table
        SelectregionsButton            matlab.ui.control.Button
        SelectsubstrateregionButton    matlab.ui.control.Button
        SelectOMimageButton            matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        RatiomapTab                    matlab.ui.container.Tab
        FilenameEditField_2            matlab.ui.control.EditField
        FilenameEditField_2Label       matlab.ui.control.Label
        SavedataButton_2               matlab.ui.control.StateButton
        ChannelDropDown_3              matlab.ui.control.DropDown
        ChannelDropDown_3Label         matlab.ui.control.Label
        CalculateButton_4              matlab.ui.control.Button
        SelectregionofinterestButton   matlab.ui.control.Button
        SelectsubstrateregionButton_2  matlab.ui.control.Button
        SelectOMimageButton_2          matlab.ui.control.Button
        UIAxes_7                       matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        I % Description
        subs % Description
        region % Description
        I_raw % Description
        ROI % Description
        rgbImage % Description
        m % Description
        n % Description
    end

    properties (Access = private)
        a % Description
        b % Description
        fname % Description
        ab % Description
        slopemap % Description
        I_R_subs % Description
        I_G_subs
        I_B_subs
        red % Description
        green
        blue
        ratio_map % Description
    end

    properties (Access = public)
        roi % Description
        CD
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SelecsubstrateregionButton_2Pushed(app, event)
            
                [app.subs, app.region(2,:)] = imcrop(app.I);
            
        end

        % Callback function
        function SelectregionofinterestButtonPushed(app, event)
           
                [app.rgbImage, app.region(1,:)] = imcrop(app.I);
                [app.m, app.n] = size(app.rgbImage(:,:,1));
            
        end

        % Value changed function: LoadOMimagesButton_2
        function LoadOMimagesButtonValueChanged(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.dng','All Image Files'};
            [file_names,folder] = uigetfile(filterspec,"MultiSelect","on");
            app.fname = strcat(folder,convertStringsToChars(file_names));
            [app.a, app.b] = size(file_names);
           
                app.ab = imread(cell2mat(app.fname(1,round(app.b/2))));
                imagesc(app.UIAxes_2,app.ab);
            
        end

        % Button pushed function: SelecsubstrateregionButton_2
        function SelecsubstrateregionButtonPushed(app, event)
         
                app.ab = imread(cell2mat(app.fname(1,round(app.b/2))));
                [app.subs, app.region(2,:)] = imcrop(app.ab);

           
        end

        % Button pushed function: SelectRegionofInterestButton
        function SelectRegionofInterestButtonPushed(app, event)
            
                [app.ROI, app.region(1,:)] = imcrop(app.ab);
                [app.m, app.n] = size(app.ROI(:,:,1));
                imagesc(app.UIAxes_8,app.ROI);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButtonPushed(app, event)
       % slope map

                p = 1;
                for i = 1:1:app.b


                    I1 = imread(cell2mat(app.fname(1,i)));
                    rgbImage = imcrop(I1,app.region(1,:));

                    A_red(:,:,p) = rgbImage(:,:,1);
                    A_green(:,:,p) = rgbImage(:,:,2);
                    A_blue(:,:,p) = rgbImage(:,:,3);


                    substrate = imcrop(I1,app.region(2,:));

                    S(1,1,p) = i;
                    S(1,2,p) = mean2(substrate(:,:,1));
                    S(1,3,p) = mean2(substrate(:,:,2));
                    S(1,4,p) = mean2(substrate(:,:,3));

                    p =p+1;
                end

                app.slopemap = zeros(app.m,app.n);

                if app.ChannelDropDown_2.Value == "Red"
                    for i = 1:1:app.m
                        for j = 1:1:app.n
                            app.slopemap(i,j) = fit(squeeze(S(1,2,:)),squeeze(A_red(i,j,:)),'poly1').p1;
                        end
                    end 


                elseif app.ChannelDropDown_2.Value == "Green"
                    for i = 1:1:app.m
                        for j = 1:1:app.n
                            app.slopemap(i,j) = fit(squeeze(S(1,3,:)),squeeze(A_green(i,j,:)),'poly1').p1;
                        end
                    end
                else
                    for i = 1:1:app.m
                        for j = 1:1:app.n
                            app.slopemap(i,j) = fit(squeeze(S(1,4,:)),squeeze(A_blue(i,j,:)),'poly1').p1;
                        end
                    end
                end

           
            imagesc(app.UIAxes_3,app.slopemap);
            colorbar(app.UIAxes_3);
        end

        % Value changed function: LoadOMimagesButton
        function LoadOMimagesButton_2ValueChanged(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.dng','All Image Files'};
            [file_names,folder] = uigetfile(filterspec,"MultiSelect","on");
            app.fname = strcat(folder,convertStringsToChars(file_names));
            [app.a, app.b] = size(file_names);
            
                  app.ab = imread(cell2mat(app.fname(1,round(app.b/2))));
                imagesc(app.UIAxes,app.ab);
            

        end

        % Value changed function: SavedataButton
        function SavedataButtonValueChanged(app, event)
            
            slopemap = app.slopemap;
            location = uigetdir;
            filename = app.FilenameEditField.Value;
            save(fullfile(location , filename) , "slopemap");
        end

        % Button pushed function: SelectRegionsButton
        function SelectRegionsButtonPushed(app, event)

            for i = 1:1:app.NumberofregionsSpinner.Value
                [app.ROI, app.region(1+i,:)] = imcrop(app.ab);
            end

        end

        % Button pushed function: SelectOMimageButton
        function SelectOMimageButtonPushed(app, event)
                filterspec = {'*.jpg;*.tif;*.png;*.gif;*.dng','All Image Files'};
                [file_names,folder] = uigetfile(filterspec);
                app.fname = strcat(folder,convertStringsToChars(file_names));
                app.ab = raw2rgb(app.fname,"ApplyContrastStretch",true,"WhiteBalanceMultipliers","ComputeFromImage","ColorSpace","camera");
                imagesc(app.UIAxes_4,app.ab);
        end

        % Button pushed function: SelectsubstrateregionButton
        function SelectsubstrateregionButtonPushed(app, event)
            [app.subs, app.roi(2,:)] = imcrop(app.ab);
            
            I_raw = rawread((app.fname));
            I_raw_planar = raw2planar(I_raw); % convert the cfa into 4 elements RGGB form
            
            app.I_R_subs = I_raw_planar( app.roi(2,2)/2:(app.roi(2,2)+app.roi(2,4))/2 , app.roi(2,1)/2:(app.roi(2,1)+app.roi(2,3))/2 ,4); % read the R 
            
            app.I_G_subs = I_raw_planar( app.roi(2,2)/2:(app.roi(2,2)+app.roi(2,4))/2 , app.roi(2,1)/2:(app.roi(2,1)+app.roi(2,3))/2 ,2); % read the G 
            
            app.I_B_subs = I_raw_planar( app.roi(2,2)/2:(app.roi(2,2)+app.roi(2,4))/2 , app.roi(2,1)/2:(app.roi(2,1)+app.roi(2,3))/2 ,1); % read the B
            
        end

        % Button pushed function: SelectregionsButton
        function SelectregionsButtonPushed(app, event)
            for i = 1:1:app.NumberofregionsSpinner_2.Value
                [app.ROI, app.region(i,:)] = imcrop(app.ab);
            end
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            for i = 1:1:app.NumberofregionsSpinner_2.Value
                I_raw = rawread((app.fname));   
                I_raw_planar = raw2planar(I_raw); % convert the cfa into 4 elements RGGB form
                
                I_R = I_raw_planar( app.region(i,2)/2:(app.region(i,2)+app.region(i,4))/2 , app.region(i,1)/2:(app.region(i,1)+app.region(i,3))/2 ,4); % read the R 
                app.red(i) = mean2(I_R);
                
                I_G = I_raw_planar( app.region(i,2)/2:(app.region(i,2)+app.region(i,4))/2 , app.region(i,1)/2:(app.region(i,1)+app.region(i,3))/2 ,2); % read the G
                app.green(i) = mean2(I_G);
                
                I_B = I_raw_planar( app.region(i,2)/2:(app.region(i,2)+app.region(i,4))/2 , app.region(i,1)/2:(app.region(i,1)+app.region(i,3))/2 ,1); % read the B
                app.blue(i) = mean2(I_B);

                
            end
            
            red_ratio = app.red /mean2(app.I_R_subs);
            green_ratio = app.green /mean2(app.I_G_subs);
            blue_ratio = app.blue /mean2(app.I_B_subs);
            
            ratio = [(1:app.NumberofregionsSpinner_2.Value)' red_ratio(:) green_ratio(:) blue_ratio(:)];
            app.UITable_2.Data = ratio;
        end

        % Button pushed function: SelectOMimageButton_2
        function SelectOMimageButton_2Pushed(app, event)
             filterspec = {'*.jpg;*.tif;*.png;*.gif;*.dng','All Image Files'};
                [file_names,folder] = uigetfile(filterspec);
                app.fname = strcat(folder,convertStringsToChars(file_names));
                app.ab = raw2rgb(app.fname,"ApplyContrastStretch",true,"WhiteBalanceMultipliers","ComputeFromImage","ColorSpace","camera");
                imagesc(app.UIAxes_5,app.ab);
        end

        % Button pushed function: SelectsubstrateregionButton_2
        function SelectsubstrateregionButton_2Pushed(app, event)
            [app.subs, app.roi(2,:)] = imcrop(app.ab);
            
            I_raw = rawread((app.fname));
            I_raw_planar = raw2planar(I_raw); % convert the cfa into 4 elements RGGB form
            
            app.I_R_subs = I_raw_planar( app.roi(2,2)/2:(app.roi(2,2)+app.roi(2,4))/2 , app.roi(2,1)/2:(app.roi(2,1)+app.roi(2,3))/2 ,4); % read the R 
            
            app.I_G_subs = I_raw_planar( app.roi(2,2)/2:(app.roi(2,2)+app.roi(2,4))/2 , app.roi(2,1)/2:(app.roi(2,1)+app.roi(2,3))/2 ,2); % read the G 
            
            app.I_B_subs = I_raw_planar( app.roi(2,2)/2:(app.roi(2,2)+app.roi(2,4))/2 , app.roi(2,1)/2:(app.roi(2,1)+app.roi(2,3))/2 ,1); % read the B
            
        end

        % Button pushed function: SelectregionofinterestButton
        function SelectregionofinterestButtonPushed2(app, event)
            [app.ROI, app.region(1,:)] = imcrop(app.ab);
            imagesc(app.UIAxes_7,app.ROI);
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
                [m, n] = size(app.ROI(:,:,1));
                I_raw = rawread((app.fname));   
                I_raw_planar = raw2planar(I_raw); % convert the cfa into 4 elements RGGB form
                
                I_R = I_raw_planar( app.region(1,2)/2:(app.region(1,2)+app.region(1,4))/2 , app.region(1,1)/2:(app.region(1,1)+app.region(1,3))/2 ,4); % read the R 
                A_red(:,:) = I_R;
                
                I_G = I_raw_planar( app.region(1,2)/2:(app.region(1,2)+app.region(1,4))/2 , app.region(1,1)/2:(app.region(1,1)+app.region(1,3))/2 ,2); % read the G
                A_green(:,:) = I_G;
                
                I_B = I_raw_planar( app.region(1,2)/2:(app.region(1,2)+app.region(1,4))/2 , app.region(1,1)/2:(app.region(1,1)+app.region(1,3))/2 ,1); % read the B
                A_blue(:,:) = I_B;

        app.ratio_map = zeros(round(m/2),round(n/2));
        if app.ChannelDropDown_3.Value == "Red"
           
            for i =1:1:round(m/2)
                for j = 1:round(n/2)
        
                app.ratio_map(i,j) = ( double(int16(A_red(i,j)))./ mean2(int16(app.I_R_subs))); % red 
              
                end 
            end 
            
        elseif app.ChannelDropDown_3.Value == "Green"   
           
            for i =1:1:round(m/2)
                for j = 1:round(n/2)
        
        
                app.ratio_map(i,j) = ( double(int16(A_green(i,j)))./ mean2(int16(app.I_G_subs))); % green
                
        
                end 
            end 
        else 
            
            for i =1:1:round(m/2)
                for j = 1:round(n/2)
        
                app.ratio_map(i,j) = ( double(int16(A_blue(i,j)))./ mean2(int16(app.I_B_subs))); % blue
             
        
                end 
            end
        end 
            
            
             imagesc(app.UIAxes_6,app.ratio_map);
            colorbar(app.UIAxes_6);
            
        end

        % Value changed function: SavedataButton_2
        function SavedataButton_2ValueChanged(app, event)
            ratio_map = app.ratio_map;
            location = uigetdir;
            filename = app.FilenameEditField_2.Value;
            save(fullfile(location , filename) , "ratio_map");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.RGBTab = uitab(app.TabGroup2);
            app.RGBTab.Title = 'RGB';
            app.TabGroup = uitabgroup(app.RGBTab);
            app.RegionalSlopeTab = uitab(app.TabGroup);
            app.RegionalSlopeTab.Title = 'Regional Slope';
            app.UIAxes = uiaxes(app.RegionalSlopeTab);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes4 = uiaxes(app.RegionalSlopeTab);
            title(app.UIAxes4, 'Substrate intensity vs flake intensity ')
            xlabel(app.UIAxes4, 'Substrate intensity ')
            ylabel(app.UIAxes4, 'Flake intensity ')
            zlabel(app.UIAxes4, 'Z')
            app.LoadOMimagesButton = uibutton(app.RegionalSlopeTab, 'state');
            app.LoadOMimagesButton.ValueChangedFcn = createCallbackFcn(app, @LoadOMimagesButton_2ValueChanged, true);
            app.LoadOMimagesButton.Text = 'Load OM images';
            app.SelecsubstrateregionButton = uibutton(app.RegionalSlopeTab, 'push');
            app.SelecsubstrateregionButton.Text = 'Selec substrate region';
            app.SelectRegionsButton = uibutton(app.RegionalSlopeTab, 'push');
            app.SelectRegionsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionsButtonPushed, true);
            app.SelectRegionsButton.Text = 'Select Regions';
            app.CalculateButton = uibutton(app.RegionalSlopeTab, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.ChannelDropDownLabel = uilabel(app.RegionalSlopeTab);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.RegionalSlopeTab);
            app.ChannelDropDown.Items = {'Red', 'Green', 'Blue'};
            app.ChannelDropDown.Value = 'Red';
            app.NumberofregionsSpinnerLabel = uilabel(app.RegionalSlopeTab);
            app.NumberofregionsSpinnerLabel.Text = 'Number of regions';
            app.NumberofregionsSpinner = uispinner(app.RegionalSlopeTab);
            app.NumberofregionsSpinner.Limits = [1 3];
            app.NumberofregionsSpinner.Value = 1;
            app.UITable = uitable(app.RegionalSlopeTab);
            app.UITable.ColumnName = {'Region'; 'Slope'};
            app.UITable.RowName = {};
            app.SlopemapTab = uitab(app.TabGroup);
            app.SlopemapTab.Title = 'Slope map';
            app.UIAxes_2 = uiaxes(app.SlopemapTab);
            title(app.UIAxes_2, 'Image')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.SlopemapTab);
            title(app.UIAxes_3, 'Slope map')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_8 = uiaxes(app.SlopemapTab);
            title(app.UIAxes_8, 'Region of interest')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.LoadOMimagesButton_2 = uibutton(app.SlopemapTab, 'state');
            app.LoadOMimagesButton_2.ValueChangedFcn = createCallbackFcn(app, @LoadOMimagesButtonValueChanged, true);
            app.LoadOMimagesButton_2.Text = 'Load OM images';
            app.SelecsubstrateregionButton_2 = uibutton(app.SlopemapTab, 'push');
            app.SelecsubstrateregionButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelecsubstrateregionButtonPushed, true);
            app.SelecsubstrateregionButton_2.Text = 'Selec substrate region';
            app.SelectRegionofInterestButton = uibutton(app.SlopemapTab, 'push');
            app.SelectRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionofInterestButtonPushed, true);
            app.SelectRegionofInterestButton.Text = 'Select Region of Interest';
            app.CalculateButton_2 = uibutton(app.SlopemapTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ChannelDropDown_2Label = uilabel(app.SlopemapTab);
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.ChannelDropDown_2 = uidropdown(app.SlopemapTab);
            app.ChannelDropDown_2.Items = {'Red', 'Green', 'Blue'};
            app.ChannelDropDown_2.Value = 'Red';
            app.SavedataButton = uibutton(app.SlopemapTab, 'state');
            app.SavedataButton.ValueChangedFcn = createCallbackFcn(app, @SavedataButtonValueChanged, true);
            app.SavedataButton.Text = 'Save data';
            app.FilenameEditFieldLabel = uilabel(app.SlopemapTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.SlopemapTab, 'text');
            app.FilenameEditField.Value = 'SlopeMap';
            app.RAWTab = uitab(app.TabGroup2);
            app.RAWTab.Title = 'RAW';
            app.TabGroup3 = uitabgroup(app.RAWTab);
            app.RegionalratioTab = uitab(app.TabGroup3);
            app.RegionalratioTab.Title = 'Regional ratio';
            app.UIAxes_4 = uiaxes(app.RegionalratioTab);
            title(app.UIAxes_4, 'Image')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.SelectOMimageButton = uibutton(app.RegionalratioTab, 'push');
            app.SelectOMimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOMimageButtonPushed, true);
            app.SelectOMimageButton.Text = 'Select OM image';
            app.SelectsubstrateregionButton = uibutton(app.RegionalratioTab, 'push');
            app.SelectsubstrateregionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsubstrateregionButtonPushed, true);
            app.SelectsubstrateregionButton.Text = 'Select substrate region';
            app.SelectregionsButton = uibutton(app.RegionalratioTab, 'push');
            app.SelectregionsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectregionsButtonPushed, true);
            app.SelectregionsButton.Text = 'Select regions';
            app.UITable_2 = uitable(app.RegionalratioTab);
            app.UITable_2.ColumnName = {'Region'; 'Ratio (Red)'; 'Ratio (Green)'; 'Ratio (Blue)'};
            app.UITable_2.RowName = {};
            app.NumberofregionsSpinner_2Label = uilabel(app.RegionalratioTab);
            app.NumberofregionsSpinner_2Label.Text = 'Number of regions';
            app.NumberofregionsSpinner_2 = uispinner(app.RegionalratioTab);
            app.NumberofregionsSpinner_2.Limits = [1 5];
            app.NumberofregionsSpinner_2.Value = 1;
            app.CalculateButton_3 = uibutton(app.RegionalratioTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.RatiomapTab = uitab(app.TabGroup3);
            app.RatiomapTab.Title = 'Ratio map';
            app.UIAxes_5 = uiaxes(app.RatiomapTab);
            title(app.UIAxes_5, 'Image')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.RatiomapTab);
            title(app.UIAxes_6, 'Ratio map')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_7 = uiaxes(app.RatiomapTab);
            title(app.UIAxes_7, 'Region of interest')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.SelectOMimageButton_2 = uibutton(app.RatiomapTab, 'push');
            app.SelectOMimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectOMimageButton_2Pushed, true);
            app.SelectOMimageButton_2.Text = 'Select OM image';
            app.SelectsubstrateregionButton_2 = uibutton(app.RatiomapTab, 'push');
            app.SelectsubstrateregionButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectsubstrateregionButton_2Pushed, true);
            app.SelectsubstrateregionButton_2.Text = 'Select substrate region';
            app.SelectregionofinterestButton = uibutton(app.RatiomapTab, 'push');
            app.SelectregionofinterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectregionofinterestButtonPushed2, true);
            app.SelectregionofinterestButton.Text = 'Select region of interest';
            app.CalculateButton_4 = uibutton(app.RatiomapTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.ChannelDropDown_3Label = uilabel(app.RatiomapTab);
            app.ChannelDropDown_3Label.Text = 'Channel';
            app.ChannelDropDown_3 = uidropdown(app.RatiomapTab);
            app.ChannelDropDown_3.Items = {'Red', 'Green', 'Blue'};
            app.ChannelDropDown_3.Value = 'Red';
            app.SavedataButton_2 = uibutton(app.RatiomapTab, 'state');
            app.SavedataButton_2.ValueChangedFcn = createCallbackFcn(app, @SavedataButton_2ValueChanged, true);
            app.SavedataButton_2.Text = 'Save data';
            app.FilenameEditField_2Label = uilabel(app.RatiomapTab);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.RatiomapTab, 'text');
            app.FilenameEditField_2.Value = 'Ratio Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLOPEY

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end